classdef PACIENTE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DatosTab                      matlab.ui.container.Tab
        CargarimagenButton            matlab.ui.control.Button
        GuardarButton                 matlab.ui.control.Button
        FechadediagnsticoDatePicker   matlab.ui.control.DatePicker
        FechadediagnsticoDatePickerLabel  matlab.ui.control.Label
        IDdelpacienteEditField        matlab.ui.control.EditField
        IDdelpacienteEditFieldLabel   matlab.ui.control.Label
        INGRESEDATOSDELPACIENTELabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        DiagnsticoTab                 matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        HistorialTab                  matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        function updateHistorial(app)
        filePac = "Datos_paciente.xlsx";
        fileEsp = "Datos_registro.xlsx";

        if ~isfile(filePac) || ~isfile(fileEsp)
            app.HistorialTable.Data = table();  
            return
        end

        Tpac = readtable(filePac); 
        Tesp = readtable(fileEsp);

        Hist = innerjoin(Tpac, Tesp, 'LeftKeys',  "IDespecialista",'RightKeys', "Identificadordeespecialista", 'RightVariables', {});
        Hist = Hist(:, {'IDpaciente','Fecha','IDespecialista'});
        Hist.Properties.VariableNames = ["ID paciente" "Fecha diagnóstico" "ID especialista"];

        app.HistorialTable.Data = Hist;
        drawnow
        end
        
        function StartupFcn(app)
            updateHistorial(app)    
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            IDdelpaciente = string(app.IDdelpacienteEditField.Value);
            Fecha = string(app.FechadediagnsticoDatePicker.Value);
            
            T = table(IDdelpaciente, Fecha);
            filename = 'Datos_paciente.xlsx';
    
            if exist(filename,'file') == 0
              writetable(T, filename, 'Sheet', 1, 'Range', 'A1');
            
            else
            datos = readtable(filename);

            Duplicado = any( strcmp(datos.IDdelpaciente, IDdelpaciente) & strcmp(datos.Fecha, Fecha))
            
            if Duplicado
                uialert(app.UIFigure, "Por favor, esto paciente ya ha sido registrado en esta fecha. Intentalo de nuevo.","Paciente ya registrado", "Icon", "warning");
                return
            end
                writetable(T, filename, 'Sheet', 1, 'WriteMode', 'append');
            end

            uialert(app.UIFigure, "Paciente registrado correctamente. Porfavor, carga la imagen.", "Registro", "Icon", "success");
            pause(1);
            updateHistorial(app) 
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
            [archivo, carpeta] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.tiff', 'Imágenes (.png,.jpg,.jpeg,*.tif)'}, ...
             'Selecciona una imagen');

             if isequal(archivo, 0)
                return
             end

            rutaImagen = fullfile(carpeta, archivo);
            img = imread(rutaImagen);
            imshow(img, 'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.UIAxes = uiaxes(app.DatosTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.INGRESEDATOSDELPACIENTELabel = uilabel(app.DatosTab);
            app.INGRESEDATOSDELPACIENTELabel.Text = 'INGRESE DATOS DEL PACIENTE';
            app.IDdelpacienteEditFieldLabel = uilabel(app.DatosTab);
            app.IDdelpacienteEditFieldLabel.Text = 'ID del paciente';
            app.IDdelpacienteEditField = uieditfield(app.DatosTab, 'text');
            app.FechadediagnsticoDatePickerLabel = uilabel(app.DatosTab);
            app.FechadediagnsticoDatePickerLabel.Text = 'Fecha de diagnóstico';
            app.FechadediagnsticoDatePicker = uidatepicker(app.DatosTab);
            app.GuardarButton = uibutton(app.DatosTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.CargarimagenButton = uibutton(app.DatosTab, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.DiagnsticoTab = uitab(app.TabGroup);
            app.DiagnsticoTab.Title = 'Diagnóstico';
            app.UIAxes2 = uiaxes(app.DiagnsticoTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HistorialTab = uitab(app.TabGroup);
            app.HistorialTab.Title = 'Historial';
            app.UITable = uitable(app.HistorialTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PACIENTE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end